classdef text_recent_prj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        NewProjectMenu         matlab.ui.container.Menu
        OpenProjectMenu        matlab.ui.container.Menu
        OpenRecentProjectMenu  matlab.ui.container.Menu
        SaveProjectMenu        matlab.ui.container.Menu
        SaveasMenu             matlab.ui.container.Menu
        ExitMenu               matlab.ui.container.Menu
    end

    
    properties (Access = private)
        reprj_path % Description
        selected_prj_path
    end
    
    methods (Access = private)
        function result = check_cell_value(app, input_cell)
            % 判断输入的 cell 数据中，有多少个空数据
            result = [];
            [cell_len, ~] = size(input_cell);
            for i=1:cell_len
                result = [result, isempty(input_cell{i})];
            end
        end

        function result = is_path_in_config(app)
            % 判断所选路径是否在最近打开队列中
            result = [];
            for i = 1:10
                if strcmp(app.selected_prj_path, app.reprj_path{i})
                    result = i;
                end
            end
        end

        % function update_recent_project_order(app, path, index)

        function update_recent_project_order(app, index)
            % if nargin < 2
            %     index = -1;
            if isempty(index)
            % 不在队列中，则往队列最前增加
                % result = app.check_cell_value(app.reprj_path);
                % path_num = 10 - sum(result);
                for i=9:-1:1
                        app.reprj_path{i+1} = app.reprj_path{i} ;
                end
                app.reprj_path{1} = app.selected_prj_path;
            else
            % 在队列中，则调整其位置至队列最前
                tmp_cell = cell(10, 1);
                step = int8(-1);
                for i =2:1:10
                    if i == index
                        tmp_cell{i} = app.reprj_path{i+step};
                        step = uint8(0);
                        continue
                    else
                        tmp_cell{i} = app.reprj_path{i+step};
                    end
                end
                tmp_cell{1} = app.selected_prj_path;
                app.reprj_path = tmp_cell;

                
                % for i = 9:-1:1
                %     disp(i)
                %     if i == (index + 1) 
                %         
                %         tmp_cell{i+1} = app.reprj_path{i-1};
                %     else
                %         if ~isempty(app.reprj_path)
                %             tmp_cell{i+1} = app.reprj_path{i-step};
                %         end
                %     end
                % end
            end

            % if nargin == 2
            %     % 路径不包含在当前最近打开项目中，则移除最后一个路径
            %     index = -1;
            %     result = app.check_cell_value(app.reprj_path);
            %     path_num = 10 - sum(result);
            %     for i = path_num:-1:2
            %         app.reprj_path{i+1} = app.reprj_path{i};
            %     end
            %     app.reprj_path{1} = path;
            % else
            %     % 路径包含在当前最近打开项目中，则将该项目顺序调整到置顶
            %     result = app.check_cell_value(app.reprj_path);
            %     path_num = 10 - sum(result(1:index));
            %     for i = path_num:-1:2
            %         app.reprj_path{i+1} = app.reprj_path{i};
            %     end
            %     app.reprj_path{1} = path;
            % end
            
        end

        function update_recent_project_submenu(app)
            % 获取 Menu 的所有直接子空间
            children = get(app.OpenRecentProjectMenu, 'Children');
            % 遍历并删除每个子控件
            for i = 1:numel(children)
                delete(children(i));
            end
            % 更新菜单子控件
            result = app.check_cell_value(app.reprj_path);
            new_menu = 10 - sum(result);
            for i=1:new_menu
                sub_menu = uimenu(app.OpenRecentProjectMenu,"Text", [num2str(i), '. ', app.reprj_path{i}]);
                sub_menu.MenuSelectedFcn = @app.open_recent_prj_menu_callback; 
            end
        end
        
        function select_path = open_recent_prj_menu_callback(app, src, event)
            select_path = src.Text(4:end);
            app.selected_prj_path = select_path;
            
            result = app.is_path_in_config();
            if isempty(result)
                app.update_recent_project_order(app.selected_prj_path)
            else
                app.update_recent_project_order(app.selected_prj_path, result)
            end
            app.update_recent_project_submenu()
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist("config.mat", "file")
                config_data = load("config.mat", 'reprj_path');
                app.reprj_path = config_data.reprj_path;
            else
                app.reprj_path = cell(10, 1);
            end
            result = app.check_cell_value(app.reprj_path);
            new_menu = 10 - sum(result);
            for i=1:new_menu
                sub_menu = uimenu(app.OpenRecentProjectMenu,"Text", [num2str(i), '. ', app.reprj_path{i}]);
                sub_menu.MenuSelectedFcn = @app.open_recent_prj_menu_callback; 
            end
            app.selected_prj_path = '';
            disp('victor')
        end

        % Menu selected function: OpenProjectMenu
        function OpenProjectMenuSelected(app, event)
            current_path = pwd;  % 获取当前工作路径
            select_path = uigetdir(current_path, "pick your folder");  % 基于当前工作路径打开文件夹选择器
            if ~isempty(select_path)
                app.selected_prj_path = select_path;  % 获取所选路径
                is_incell = app.is_path_in_config();  % 判断路径是否在队列中
                app.update_recent_project_order(is_incell);  % 重新调整队列顺序
                app.update_recent_project_submenu();    % 更新子菜单栏
            end
            %
            % for i = 1:10
            %     if result(i) == 1
            %         app.reprj_path{i} = select_path;
            %         break
            %     end
            % end
            % app.reprj_path{} = [app.reprj_path; app.selected_prj_path];
            % disp(app.reprj_path);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % msgbox("close this window.");
            is_close_app = false;
            result = uiconfirm(app.UIFigure, "Please confirm whether close this app","Close confirm", ...
                "Options", {'Yes', 'No'}, 'DefaultOption',2, 'CancelOption',2);
            switch result
                case 'Yes'
                    is_close_app = true;
                case 'No'
                    is_close_app = false;
            end
            if is_close_app
                reprj_path = app.reprj_path;
                save('config.mat', 'reprj_path');
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewProjectMenu = uimenu(app.FileMenu);
            app.NewProjectMenu.Text = 'New Project';
            app.OpenProjectMenu = uimenu(app.FileMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProjectMenuSelected, true);
            app.OpenProjectMenu.Text = 'Open Project';
            app.OpenRecentProjectMenu = uimenu(app.FileMenu);
            app.OpenRecentProjectMenu.Text = 'Open Recent Project';
            app.SaveProjectMenu = uimenu(app.FileMenu);
            app.SaveProjectMenu.Text = 'Save Project';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Text = 'Save as ...';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = text_recent_prj

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end